classdef oscilloscope_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MainViewTab                  matlab.ui.container.Tab
        DAQLampLabel                 matlab.ui.control.Label
        DAQLamp                      matlab.ui.control.Lamp
        PortDropDownLabel            matlab.ui.control.Label
        PortDropDown                 matlab.ui.control.DropDown
        ModeSwitchLabel              matlab.ui.control.Label
        ModeSwitch                   matlab.ui.control.Switch
        SimsignalDropDownLabel       matlab.ui.control.Label
        SimsignalDropDown            matlab.ui.control.DropDown
        STARTButton                  matlab.ui.control.StateButton
        NumpointsSpinnerLabel        matlab.ui.control.Label
        NumpointsSpinner             matlab.ui.control.Spinner
        AcquisitionrateSpinnerLabel  matlab.ui.control.Label
        AcquisitionrateSpinner       matlab.ui.control.Spinner
        SignalTab                    matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        RawSignalCheckBox            matlab.ui.control.CheckBox
        FilteredSignalCheckBox       matlab.ui.control.CheckBox
        FrequencyHzEditFieldLabel    matlab.ui.control.Label
        FrequencyHzEditField         matlab.ui.control.NumericEditField
        AmplitudeVEditFieldLabel     matlab.ui.control.Label
        AmplitudeVEditField          matlab.ui.control.NumericEditField
        DCVEditFieldLabel            matlab.ui.control.Label
        DCVEditField                 matlab.ui.control.NumericEditField
        PeriodsEditFieldLabel        matlab.ui.control.Label
        PeriodsEditField             matlab.ui.control.NumericEditField
        MaxVEditFieldLabel           matlab.ui.control.Label
        MaxVEditField                matlab.ui.control.NumericEditField
        MinVEditFieldLabel           matlab.ui.control.Label
        MinVEditField                matlab.ui.control.NumericEditField
        ACRMSVEditFieldLabel         matlab.ui.control.Label
        ACRMSVEditField              matlab.ui.control.NumericEditField
        AutoTuneButton               matlab.ui.control.StateButton
        OhmPowEditFieldLabel         matlab.ui.control.Label
        OhmPowEditField              matlab.ui.control.EditField
        AcqTimesEditFieldLabel       matlab.ui.control.Label
        AcqTimesEditField            matlab.ui.control.EditField
        SamplessEditFieldLabel       matlab.ui.control.Label
        SamplessEditField            matlab.ui.control.EditField
        FFTTab                       matlab.ui.container.Tab
        UIAxes_2                     matlab.ui.control.UIAxes
        RawFFTCheckBox               matlab.ui.control.CheckBox
        FilteredFFTCheckBox          matlab.ui.control.CheckBox
        FilterTab                    matlab.ui.container.Tab
        UIAxes_3                     matlab.ui.control.UIAxes
        ModelDropDownLabel           matlab.ui.control.Label
        ModelDropDown                matlab.ui.control.DropDown
        ShowFFTCheckBox              matlab.ui.control.CheckBox
        CutoffFreqSpinnerLabel       matlab.ui.control.Label
        CutoffFreqSpinner            matlab.ui.control.Spinner
        TypeDropDown_2Label          matlab.ui.control.Label
        TypeDropDown_2               matlab.ui.control.DropDown
        OrderSpinnerLabel            matlab.ui.control.Label
        OrderSpinner                 matlab.ui.control.Spinner
        SaveTab                      matlab.ui.container.Tab
        FilenameEditFieldLabel       matlab.ui.control.Label
        FilenameEditField            matlab.ui.control.EditField
        SaveButton_2                 matlab.ui.control.Button
        AboutTab                     matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
        label_name                   matlab.ui.control.Label
        label_project                matlab.ui.control.Label
        label_bug                    matlab.ui.control.Label
        label_resume                 matlab.ui.control.Label
        label_git                    matlab.ui.control.Label
        OFFLampLabel                 matlab.ui.control.Label
        OFFLamp                      matlab.ui.control.Lamp
        StatusEditFieldLabel         matlab.ui.control.Label
        StatusEditField              matlab.ui.control.EditField
        MainButton                   matlab.ui.control.StateButton
        SignalButton                 matlab.ui.control.StateButton
        FFTButton                    matlab.ui.control.StateButton
        FilterButton                 matlab.ui.control.StateButton
        SaveButton                   matlab.ui.control.StateButton
        AboutButton                  matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        kMax = 1000;
        DaqSession
        SimSignal   = load('SineWaveData_8192.dat');
        simTime     = 0:0.00929:8192*0.00929;
        simArray    = zeros(1, 1000);
        RTSignal    = zeros(1, 1000);
        timeArray   = zeros(1, 1000);
        SamplingRate = 1000;
        filterType  = 'low';
        freqArray
        Signal
        FFTSignal
        Filter
        FilteredSignal
        FilteredFFT
        analogChannel = 1;
        PlotFFTwFilter = false;
        PlotFilteredSignal = false;
        PlotFilteredFFT = false;
        GREEN       = [0 1 0];
        RED         = [1 0 0];
    end
    
    methods (Access = private)        
        function PlotSignal(app)            
            if(strcmp(app.ModeSwitch.Value, 'Realtime'))
                %input_data = app.DaqSession.inputSingleScan;
                tic;
                [data,timestamps,~] = startForeground(app.DaqSession);
                app.AcqTimesEditField.Value = string(toc);
                app.RTSignal = data(:,app.analogChannel);
                app.timeArray = timestamps;
                app.Signal = data;
                app.Signal = app.RTSignal;
                
                % Sampling frequency
                app.SamplingRate = app.DaqSession.Rate;
            else
                app.simArray = app.SimSignal(1:app.kMax);
                app.timeArray = app.simTime(1:app.kMax);
                
                app.AcqTimesEditField.Value = 'Simulation';
                
                app.Signal = app.simArray;
                
                % Sampling frequency
                app.SamplingRate = 107.64;
            end
            
            % Signal length
            L = length(app.Signal);
            
            app.FFTSignal = fft(app.Signal);
            app.freqArray = app.SamplingRate*(0:L-1)/L;
            
            app.SamplessEditField.Value = string(app.SamplingRate);
            
            % Make sure filter was created according the input signal
            app.createFilter();
            
            %app.FFTSignal
            %app.Filter
            %size(app.FFTSignal(:).*app.Filter(:))
            
            app.FilteredFFT = app.FFTSignal(:).*app.Filter(:);
            app.FilteredSignal = ifft(app.FilteredFFT);
                
            %% Signal tab
            if(app.TabGroup.SelectedTab == app.SignalTab)
                y = [];
                
                if(app.RawSignalCheckBox.Value)
                    y = [y, app.Signal];
                end
                
                if(app.FilteredSignalCheckBox.Value)
                    y = [y, app.FilteredSignal];
                end 
                
                if(~isempty(y))
                    plot(app.UIAxes, app.timeArray, y);
                end
                
                % Frequency calculation
                [pks, locs] = findpeaks(abs(app.FFTSignal));                
                freq_peak = max(pks(locs > 1));
                freq = app.freqArray(min(locs(pks==freq_peak)));
                if(length(pks) <= 1)
                    freq = 0;
                end
                app.FrequencyHzEditField.Value = freq;
                
                % Period calculation
                app.PeriodsEditField.Value = 1/freq;
                
                % Amplitude calculation
                app.AmplitudeVEditField.Value = (max(app.Signal) - min(app.Signal))/2;
                app.MaxVEditField.Value = max(app.Signal);
                app.MinVEditField.Value = min(app.Signal);
                
                % DC value
                app.DCVEditField.Value = mean(app.Signal);
                
                % AC RMS
                app.ACRMSVEditField.Value = sqrt(mean((app.Signal - mean(app.Signal)).*(app.Signal - mean(app.Signal))));
                app.OhmPowEditField.Value = string(sqrt(app.DCVEditField.Value^2 + app.ACRMSVEditField.Value^2));
            end
                
            %% FFT tab
            if(app.TabGroup.SelectedTab==app.FFTTab)
                if(app.PlotFilteredFFT)
                    plot(app.UIAxes_2, app.freqArray(1:length(app.FFTSignal)/2), abs(app.FFTSignal(1:length(app.FFTSignal)/2))/L, app.freqArray(1:length(app.FFTSignal)/2), abs(app.FilteredFFT(1:length(app.FFTSignal)/2))/L);
                else
                    plot(app.UIAxes_2, app.freqArray(1:length(app.FFTSignal)/2), abs(app.FFTSignal(1:length(app.FFTSignal)/2))/L);
                end
            end
                    
            %% Filter tab
            if(app.TabGroup.SelectedTab==app.FilterTab)
                if(app.PlotFFTwFilter)
                    plot(app.UIAxes_3, app.freqArray(1:length(app.FFTSignal)/2), abs(app.FFTSignal(1:length(app.FFTSignal)/2))/L, app.freqArray(1:length(app.FFTSignal)/2), app.Filter(1:length(app.FFTSignal)/2));
                else
                    size(app.Filter)
                    plot(app.UIAxes_3, app.freqArray(1:length(app.FFTSignal)/2), app.Filter(1:length(app.FFTSignal)/2));
                end
            end
        end
        
        function createFilter(app)
            cutoffFreq = app.CutoffFreqSpinner.Value;
            filterOrder = app.OrderSpinner.Value;
            % Limit cutoff frequence
            cutoffFreq = min(cutoffFreq, length(app.FFTSignal)/2);
            
            brickSize = round(cutoffFreq*length(app.FFTSignal)/app.SamplingRate);
        
            if(strcmp(app.ModelDropDown.Value, 'Brick'))
                if(strcmp(app.filterType, 'low'))
                    app.Filter = [ones(1, brickSize), zeros(1,length(app.FFTSignal)-2*brickSize), ones(1, brickSize)];
                elseif(strcmp(app.filterType, 'high'))
                    app.Filter = [zeros(1,round(cutoffFreq)), ones(1,length(app.FFTSignal)-2*round(cutoffFreq)), zeros(1,round(cutoffFreq))];
                end
            
            elseif(strcmp(app.ModelDropDown.Value, 'Butterworth'))
                [b,a] = butter(filterOrder, cutoffFreq/(app.SamplingRate/2), app.filterType);
                h = freqz(b,a,app.freqArray/app.freqArray(end)*2*pi);
                app.Filter = h(:);
                
            elseif(strcmp(app.ModelDropDown.Value, 'Chebyshev'))
                [b,a] = cheby1(filterOrder, 3, cutoffFreq/(app.SamplingRate/2), app.filterType);
                h = freqz(b,a,app.freqArray/app.freqArray(end)*2*pi);
                app.Filter = h(:);
            end
            
        end
        
        function mainLoop(app, value)
            try
                while value
                    value = app.STARTButton.Value;
                    app.PlotSignal();
                    drawnow();
                end
            catch
                disp('App was closed.')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            close all
            
            try
                app.DaqSession = daq.createSession('ni');
                release(app.DaqSession)
                app.DaqSession.Rate = app.SamplingRate;
                
                % ========================== Setup Analog Input ==============================
                Ch_input = app.DaqSession.addAnalogInputChannel('Dev1',0:3,'Voltage');
                set(Ch_input,'TerminalConfig','SingleEnded');
                app.DAQLamp.Color = app.GREEN;
            catch
                app.StatusEditField.Value = 'NI DAQ is not connected';
                app.DAQLamp.Color = app.RED;
            end
        end

        % Value changed function: MainButton
        function MainButtonValueChanged(app, event)
            app.MainButton.Value = true;
            app.SignalButton.Value = false;
            app.FFTButton.Value = false;
            app.FilterButton.Value = false;
            app.SaveButton.Value = false;
            app.AboutButton.Value = false;
            app.TabGroup.SelectedTab = app.MainViewTab;
        end

        % Value changed function: SignalButton
        function SignalButtonValueChanged(app, event)
            app.MainButton.Value = false;
            app.SignalButton.Value = true;
            app.FFTButton.Value = false;
            app.FilterButton.Value = false;
            app.SaveButton.Value = false;
            app.AboutButton.Value = false;
            app.TabGroup.SelectedTab = app.SignalTab;
        end

        % Value changed function: FFTButton
        function FFTButtonValueChanged(app, event)
            app.MainButton.Value = false;
            app.SignalButton.Value = false;
            app.FFTButton.Value = true;
            app.FilterButton.Value = false;
            app.SaveButton.Value = false;
            app.AboutButton.Value = false;
            app.TabGroup.SelectedTab = app.FFTTab;
        end

        % Value changed function: FilterButton
        function FilterButtonValueChanged(app, event)
            app.MainButton.Value = false;
            app.SignalButton.Value = false;
            app.FFTButton.Value = false;
            app.FilterButton.Value = true;
            app.SaveButton.Value = false;
            app.AboutButton.Value = false;
            app.TabGroup.SelectedTab = app.FilterTab;
        end

        % Value changed function: SaveButton
        function SaveButtonValueChanged(app, event)
            app.MainButton.Value = false;
            app.SignalButton.Value = false;
            app.FFTButton.Value = false;
            app.FilterButton.Value = false;
            app.SaveButton.Value = true;
            app.AboutButton.Value = false;
            app.TabGroup.SelectedTab = app.SaveTab;
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            value = app.ModeSwitch.Value;
            
            if(strcmp(value, 'Realtime'))
                app.PortDropDown.Visible = 'on';
                app.PortDropDownLabel.Visible = 'on';
                app.SimsignalDropDown.Visible = 'off';
                app.SimsignalDropDownLabel.Visible = 'off';
                app.AcquisitionrateSpinner.Enable = 'on';
            else
                app.PortDropDown.Visible = 'off';
                app.PortDropDownLabel.Visible = 'off';
                app.SimsignalDropDown.Visible = 'on';
                app.SimsignalDropDownLabel.Visible = 'on';
                app.AcquisitionrateSpinner.Enable = 'off';
            end
        end

        % Value changed function: SimsignalDropDown
        function SimsignalDropDownValueChanged(app, event)
            value = app.SimsignalDropDown.Value;
            %path = mfilename('fullpath');
            %path = erase(path,"\oscilloscope_project");
            path = '';
            
            if(strcmp(value, 'Sine wave'))
                fullPath = fullfile(path, 'SineWaveData_8192.dat');
            elseif(strcmp(value, 'Square wave'))
                fullPath = fullfile(path, 'SquareWaveData_8192.dat');
            elseif(strcmp(value, 'Saw wave'))
                fullPath = fullfile(path, 'TriangularWaveData_8192.dat');
            end
            
            signal = load(fullPath);
            app.SimSignal = signal;
        end

        % Value changed function: STARTButton
        function STARTButtonValueChanged(app, event)
            value = app.STARTButton.Value;
            if(value)
                app.OFFLamp.Color = app.GREEN;
                app.OFFLampLabel.Text = "ON";
                app.AcquisitionrateSpinner.Enable = 'off';
                app.NumpointsSpinner.Enable = 'off';
            else
                app.OFFLamp.Color = app.RED;
                app.OFFLampLabel.Text = "OFF";
                app.AcquisitionrateSpinner.Enable = 'on';
                app.NumpointsSpinner.Enable = 'on';
            end
            
            app.mainLoop(value);
        end

        % Value changed function: ShowFFTCheckBox
        function ShowFFTCheckBoxValueChanged(app, event)
             value = app.ShowFFTCheckBox.Value;
             app.PlotFFTwFilter = value;
        end

        % Value changed function: FilteredFFTCheckBox
        function FilteredFFTCheckBoxValueChanged(app, event)
            value = app.FilteredFFTCheckBox.Value;
            app.PlotFilteredFFT = value;
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            value = app.PortDropDown.Value;
            
            if(strcmp(value, 'Analog 0'))
                app.analogChannel = 1;
            elseif(strcmp(value, 'Analog 1'))
                app.analogChannel = 2;
            elseif(strcmp(value, 'Analog 2'))
                app.analogChannel = 3;
            elseif(strcmp(value, 'Analog 3'))
                app.analogChannel = 4;
            end
        end

        % Value changed function: NumpointsSpinner
        function NumpointsSpinnerValueChanged(app, event)
            value = app.NumpointsSpinner.Value;
            app.kMax = value;
            app.simArray = zeros(1, value);
            app.RTSignal = zeros(1, value);
            app.timeArray = zeros(1, value);
            
            if(strcmp(app.ModeSwitch.Value, 'Realtime'))
                if(app.STARTButton.Value)
                    app.StatusEditField.Value = "Please, stop acquisition data before changing number of points.";
                else
                    app.DaqSession.NumberOfScans = value;
                end
            end
        end

        % Value changed function: AboutButton
        function AboutButtonValueChanged(app, event)
            app.MainButton.Value = false;
            app.SignalButton.Value = false;
            app.FFTButton.Value = false;
            app.FilterButton.Value = false;
            app.SaveButton.Value = false;
            app.AboutButton.Value = true;
            app.TabGroup.SelectedTab = app.AboutTab;
        end

        % Value changed function: AcquisitionrateSpinner
        function AcquisitionrateSpinnerValueChanged(app, event)
            value = app.AcquisitionrateSpinner.Value;
            if(app.STARTButton.Value)
                app.StatusEditField.Value = "Please, stop acquisition data before changing acquisition rate.";
            else
                while(~app.DaqSession.IsDone())
                    app.StatusEditField.Value = "Waiting for data acquisition to end the collection before changing acquistion rate.";
                    pause(0.1);
                end
                
                app.StatusEditField.Value = "";
                app.DaqSession.Rate = value;
            end
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            filename = app.FilenameEditField.Value + ".mat";
            time = app.timeArray;
            signal = app.Signal;
            save(filename, 'time', 'signal');
        end

        % Value changed function: TypeDropDown_2
        function TypeDropDown_2ValueChanged(app, event)
            value = app.TypeDropDown_2.Value;
            if(strcmp(value, 'Low-pass'))
                app.filterType = 'low';
            elseif(strcmp(value, 'High-pass'))
                app.filterType = 'high';
            end
        end

        % Value changed function: AutoTuneButton
        function AutoTuneButtonValueChanged(app, event)
            value = app.AutoTuneButton.Value;
            if(value)
                app.UIAxes.XLim = [0 app.PeriodsEditField.Value*1.1];
            else
                app.UIAxes.XLim = [0 inf];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainViewTab = uitab(app.TabGroup);
            app.MainViewTab.Title = 'Main View';
            app.DAQLampLabel = uilabel(app.MainViewTab);
            app.DAQLampLabel.Text = 'DAQ';
            app.DAQLamp = uilamp(app.MainViewTab);
            app.PortDropDownLabel = uilabel(app.MainViewTab);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.MainViewTab);
            app.PortDropDown.Items = {'Analog 0', 'Analog 1', 'Analog 2', 'Analog 3'};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = 'Analog 0';
            app.ModeSwitchLabel = uilabel(app.MainViewTab);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.MainViewTab, 'slider');
            app.ModeSwitch.Items = {'Realtime', 'Simulation'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Realtime';
            app.SimsignalDropDownLabel = uilabel(app.MainViewTab);
            app.SimsignalDropDownLabel.Visible = 'off';
            app.SimsignalDropDownLabel.Text = 'Sim signal';
            app.SimsignalDropDown = uidropdown(app.MainViewTab);
            app.SimsignalDropDown.Items = {'Sine wave', 'Square wave', 'Saw wave'};
            app.SimsignalDropDown.ValueChangedFcn = createCallbackFcn(app, @SimsignalDropDownValueChanged, true);
            app.SimsignalDropDown.Visible = 'off';
            app.SimsignalDropDown.Value = 'Sine wave';
            app.STARTButton = uibutton(app.MainViewTab, 'state');
            app.STARTButton.ValueChangedFcn = createCallbackFcn(app, @STARTButtonValueChanged, true);
            app.STARTButton.Text = 'START';
            app.NumpointsSpinnerLabel = uilabel(app.MainViewTab);
            app.NumpointsSpinnerLabel.Text = 'Num points';
            app.NumpointsSpinner = uispinner(app.MainViewTab);
            app.NumpointsSpinner.Limits = [10 10000];
            app.NumpointsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumpointsSpinnerValueChanged, true);
            app.NumpointsSpinner.Value = 1000;
            app.AcquisitionrateSpinnerLabel = uilabel(app.MainViewTab);
            app.AcquisitionrateSpinnerLabel.Text = 'Acquisition rate';
            app.AcquisitionrateSpinner = uispinner(app.MainViewTab);
            app.AcquisitionrateSpinner.Limits = [10 10000];
            app.AcquisitionrateSpinner.ValueChangedFcn = createCallbackFcn(app, @AcquisitionrateSpinnerValueChanged, true);
            app.AcquisitionrateSpinner.Value = 1000;
            app.SignalTab = uitab(app.TabGroup);
            app.SignalTab.Title = 'Signal';
            app.UIAxes = uiaxes(app.SignalTab);
            title(app.UIAxes, 'Input Signal')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.RawSignalCheckBox = uicheckbox(app.SignalTab);
            app.RawSignalCheckBox.Text = 'Raw Signal';
            app.RawSignalCheckBox.Value = true;
            app.FilteredSignalCheckBox = uicheckbox(app.SignalTab);
            app.FilteredSignalCheckBox.Text = 'Filtered Signal';
            app.FrequencyHzEditFieldLabel = uilabel(app.SignalTab);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.SignalTab, 'numeric');
            app.FrequencyHzEditField.Editable = 'off';
            app.AmplitudeVEditFieldLabel = uilabel(app.SignalTab);
            app.AmplitudeVEditFieldLabel.Text = 'Amplitude (V)';
            app.AmplitudeVEditField = uieditfield(app.SignalTab, 'numeric');
            app.AmplitudeVEditField.Editable = 'off';
            app.DCVEditFieldLabel = uilabel(app.SignalTab);
            app.DCVEditFieldLabel.Text = 'DC (V)';
            app.DCVEditField = uieditfield(app.SignalTab, 'numeric');
            app.DCVEditField.Editable = 'off';
            app.PeriodsEditFieldLabel = uilabel(app.SignalTab);
            app.PeriodsEditFieldLabel.Text = 'Period (s)';
            app.PeriodsEditField = uieditfield(app.SignalTab, 'numeric');
            app.PeriodsEditField.Editable = 'off';
            app.MaxVEditFieldLabel = uilabel(app.SignalTab);
            app.MaxVEditFieldLabel.Text = 'Max (V)';
            app.MaxVEditField = uieditfield(app.SignalTab, 'numeric');
            app.MaxVEditField.Editable = 'off';
            app.MinVEditFieldLabel = uilabel(app.SignalTab);
            app.MinVEditFieldLabel.Text = 'Min (V)';
            app.MinVEditField = uieditfield(app.SignalTab, 'numeric');
            app.MinVEditField.Editable = 'off';
            app.ACRMSVEditFieldLabel = uilabel(app.SignalTab);
            app.ACRMSVEditFieldLabel.Text = 'AC RMS (V)';
            app.ACRMSVEditField = uieditfield(app.SignalTab, 'numeric');
            app.ACRMSVEditField.Editable = 'off';
            app.AutoTuneButton = uibutton(app.SignalTab, 'state');
            app.AutoTuneButton.ValueChangedFcn = createCallbackFcn(app, @AutoTuneButtonValueChanged, true);
            app.AutoTuneButton.Text = 'Auto Tune';
            app.OhmPowEditFieldLabel = uilabel(app.SignalTab);
            app.OhmPowEditFieldLabel.Text = '1 Ohm Pow';
            app.OhmPowEditField = uieditfield(app.SignalTab, 'text');
            app.OhmPowEditField.Value = '0';
            app.AcqTimesEditFieldLabel = uilabel(app.SignalTab);
            app.AcqTimesEditFieldLabel.Text = 'Acq. Time (s)';
            app.AcqTimesEditField = uieditfield(app.SignalTab, 'text');
            app.AcqTimesEditField.Editable = 'off';
            app.AcqTimesEditField.Value = '0';
            app.SamplessEditFieldLabel = uilabel(app.SignalTab);
            app.SamplessEditFieldLabel.Text = 'Samples/s';
            app.SamplessEditField = uieditfield(app.SignalTab, 'text');
            app.SamplessEditField.Editable = 'off';
            app.SamplessEditField.Value = '0';
            app.FFTTab = uitab(app.TabGroup);
            app.FFTTab.Title = 'FFT';
            app.UIAxes_2 = uiaxes(app.FFTTab);
            title(app.UIAxes_2, 'Double-Sided Amplitude Spectrum')
            xlabel(app.UIAxes_2, 'frequency (Hz)')
            ylabel(app.UIAxes_2, 'Amplitude')
            app.RawFFTCheckBox = uicheckbox(app.FFTTab);
            app.RawFFTCheckBox.Text = 'Raw FFT';
            app.RawFFTCheckBox.Value = true;
            app.FilteredFFTCheckBox = uicheckbox(app.FFTTab);
            app.FilteredFFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilteredFFTCheckBoxValueChanged, true);
            app.FilteredFFTCheckBox.Text = 'Filtered FFT';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filter';
            app.UIAxes_3 = uiaxes(app.FilterTab);
            title(app.UIAxes_3, 'Filter')
            xlabel(app.UIAxes_3, 'frequency (Hz)')
            ylabel(app.UIAxes_3, 'Amplitude (normalized)')
            app.ModelDropDownLabel = uilabel(app.FilterTab);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.FilterTab);
            app.ModelDropDown.Items = {'Brick', 'Butterworth', 'Chebyshev'};
            app.ModelDropDown.Value = 'Brick';
            app.ShowFFTCheckBox = uicheckbox(app.FilterTab);
            app.ShowFFTCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowFFTCheckBoxValueChanged, true);
            app.ShowFFTCheckBox.Text = 'Show FFT';
            app.CutoffFreqSpinnerLabel = uilabel(app.FilterTab);
            app.CutoffFreqSpinnerLabel.Text = 'Cutoff Freq.';
            app.CutoffFreqSpinner = uispinner(app.FilterTab);
            app.CutoffFreqSpinner.Limits = [1 Inf];
            app.CutoffFreqSpinner.Value = 1;
            app.TypeDropDown_2Label = uilabel(app.FilterTab);
            app.TypeDropDown_2Label.Text = 'Type';
            app.TypeDropDown_2 = uidropdown(app.FilterTab);
            app.TypeDropDown_2.Items = {'Low-pass', 'High-pass'};
            app.TypeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TypeDropDown_2ValueChanged, true);
            app.TypeDropDown_2.Value = 'Low-pass';
            app.OrderSpinnerLabel = uilabel(app.FilterTab);
            app.OrderSpinnerLabel.Text = 'Order';
            app.OrderSpinner = uispinner(app.FilterTab);
            app.OrderSpinner.Limits = [1 Inf];
            app.OrderSpinner.Value = 1;
            app.SaveTab = uitab(app.TabGroup);
            app.SaveTab.Title = 'Save';
            app.FilenameEditFieldLabel = uilabel(app.SaveTab);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.SaveTab, 'text');
            app.SaveButton_2 = uibutton(app.SaveTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = 'Photo 25-12-18 13 03 50.jpg';
            app.label_name = uilabel(app.AboutTab);
            app.label_name.Text = 'Mateus Valverde Gasparino';
            app.label_project = uilabel(app.AboutTab);
            app.label_project.Text = {'This app was developed as the '; 'final project for the course ABE 425'; 'in the University of Illinois'; 'at Urbana-Champaign'};
            app.label_bug = uilabel(app.AboutTab);
            app.label_bug.Text = {'If you find any bug or malfunction, please,'; 'report at:'};
            app.label_resume = uilabel(app.AboutTab);
            app.label_resume.Text = {'Bachelor in Mechatronics Engineering'; 'at University of São Paulo, Brazil'; ''; 'Masters in Mechanical Engineering at'; 'University of São Paulo, Brazil'; ''; 'Ph.D. student in Agriculture and Biological'; 'Engineering at University of Illinois at U-C'};
            app.label_git = uilabel(app.AboutTab);
            app.label_git.Text = ' https://github.com/matval/digital_oscilloscope';
            app.OFFLampLabel = uilabel(app.UIFigure);
            app.OFFLampLabel.Text = 'OFF';
            app.OFFLamp = uilamp(app.UIFigure);
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.MainButton = uibutton(app.UIFigure, 'state');
            app.MainButton.ValueChangedFcn = createCallbackFcn(app, @MainButtonValueChanged, true);
            app.MainButton.Text = 'Main';
            app.MainButton.Value = true;
            app.SignalButton = uibutton(app.UIFigure, 'state');
            app.SignalButton.ValueChangedFcn = createCallbackFcn(app, @SignalButtonValueChanged, true);
            app.SignalButton.Text = 'Signal';
            app.FFTButton = uibutton(app.UIFigure, 'state');
            app.FFTButton.ValueChangedFcn = createCallbackFcn(app, @FFTButtonValueChanged, true);
            app.FFTButton.Text = 'FFT';
            app.FilterButton = uibutton(app.UIFigure, 'state');
            app.FilterButton.ValueChangedFcn = createCallbackFcn(app, @FilterButtonValueChanged, true);
            app.FilterButton.Text = 'Filter';
            app.SaveButton = uibutton(app.UIFigure, 'state');
            app.SaveButton.ValueChangedFcn = createCallbackFcn(app, @SaveButtonValueChanged, true);
            app.SaveButton.Text = 'Save';
            app.AboutButton = uibutton(app.UIFigure, 'state');
            app.AboutButton.ValueChangedFcn = createCallbackFcn(app, @AboutButtonValueChanged, true);
            app.AboutButton.Text = 'About';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oscilloscope_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end